classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        PlayButton                   matlab.ui.control.Button
        RewindButton                 matlab.ui.control.Button
        ForwardButton                matlab.ui.control.Button
        SearchButton                 matlab.ui.control.Button
        CurrentFrameEditFieldLabel   matlab.ui.control.Label
        CurrentFrameEditField        matlab.ui.control.NumericEditField
        PreviousFrameEditFieldLabel  matlab.ui.control.Label
        PreviousFrameEditField       matlab.ui.control.NumericEditField
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        obj = 0; % will contain video object of whatever video user chooses
        Pause = false;
        curr_frame = 1;
        prev_frame = 1;
    end
    
    methods (Access = private)
        function play_video(app)
            
            while hasFrame(app.obj)
                app.prev_frame = app.curr_frame;
                if app.Pause == true
                    break
                end
                vidFrame = readFrame(app.obj);
                app.curr_frame = app.curr_frame + 1;
                image(vidFrame, 'Parent', app.UIAxes);
                app.UIAxes.Visible = "off";
                app.CurrentFrameEditField.Value = app.curr_frame;
                app.PreviousFrameEditField.Value = app.prev_frame;
            end
            if ~hasFrame(app.obj)
                app.PlayButton.Text = "Play Again";
            end
        end 
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            if strcmp(app.PlayButton.Text, "Play")
                app.PlayButton.Text = "Pause";  
                app.Pause = false;
                play_video(app);
                
            elseif strcmp(app.PlayButton.Text, "Pause") 
                app.PlayButton.Text = "Play";
                app.Pause = true;
                
            else 
                app.PlayButton.Text = "Pause";
                app.Pause = false;
                app.curr_frame = 0;
                app.prev_frame = 0;
                app.obj.CurrentTime = 0;
                play_video(app);
            end
        end

        % Button pushed function: SearchButton
        function SearchButtonPushed(app, event)
            file = uigetfile('*.avi');
            app.obj = VideoReader(file);
            vidFrame = readFrame(app.obj);
            image(vidFrame, 'Parent', app.UIAxes);
            set(app.UIAxes, 'Visible', 'off');
            app.curr_frame = 1;
            app.prev_frame = 1;
            app.CurrentFrameEditField.Value = app.curr_frame;
            app.PreviousFrameEditField.Value = app.prev_frame;
        end

        % Button pushed function: RewindButton
        function RewindButtonPushed(app, event)
            if app.curr_frame == 1
                frame = read(app.obj, app.curr_frame);
                app.CurrentFrameEditField.Value = app.curr_frame;
                app.PreviousFrameEditField.Value = app.prev_frame;
                image(frame, 'Parent', app.UIAxes);
                set(app.UIAxes, 'Visible', 'off'); 
    
            else 
                frame = read(app.obj, app.curr_frame - 1);
                if app.curr_frame == app.obj.NumFrames
                    app.PlayButton.Text = 'Play';
                end
                app.curr_frame = app.curr_frame - 1;
                app.prev_frame = app.prev_frame - 1;
                app.CurrentFrameEditField.Value = app.curr_frame;
                app.PreviousFrameEditField.Value = app.prev_frame - 1;
                image(frame, 'Parent', app.UIAxes);
                set(app.UIAxes, 'Visible', 'off');  
            end
            
        end

        % Button pushed function: ForwardButton
        function ForwardButtonPushed(app, event)
            if app.curr_frame < app.obj.NumFrames
                
                frame = read(app.obj, app.curr_frame + 1);
                app.curr_frame = app.curr_frame + 1;
                app.prev_frame = app.prev_frame + 1;
                app.CurrentFrameEditField.Value = app.curr_frame;
                app.PreviousFrameEditField.Value = app.prev_frame - 1;
                image(frame, 'Parent', app.UIAxes);
                set(app.UIAxes, 'Visible', 'off'); 
            else 
                frame = read(app.obj, app.curr_frame);
                image(frame, 'Parent', app.UIAxes);
                set(app.UIAxes, 'Visible', 'off'); 
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.RewindButton = uibutton(app.UIFigure, 'push');
            app.RewindButton.ButtonPushedFcn = createCallbackFcn(app, @RewindButtonPushed, true);
            app.RewindButton.Text = 'Rewind';
            app.ForwardButton = uibutton(app.UIFigure, 'push');
            app.ForwardButton.ButtonPushedFcn = createCallbackFcn(app, @ForwardButtonPushed, true);
            app.ForwardButton.Text = 'Forward';
            app.SearchButton = uibutton(app.UIFigure, 'push');
            app.SearchButton.ButtonPushedFcn = createCallbackFcn(app, @SearchButtonPushed, true);
            app.SearchButton.Text = 'Search';
            app.CurrentFrameEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentFrameEditFieldLabel.Text = 'Current Frame';
            app.CurrentFrameEditField = uieditfield(app.UIFigure, 'numeric');
            app.PreviousFrameEditFieldLabel = uilabel(app.UIFigure);
            app.PreviousFrameEditFieldLabel.Text = 'Previous Frame';
            app.PreviousFrameEditField = uieditfield(app.UIFigure, 'numeric');
            app.UIAxes = uiaxes(app.UIFigure);
            zlabel(app.UIAxes, 'Z\')
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end